classdef app_Dec_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Step4CalculatethefluxesLabel  matlab.ui.control.Label
        Step3SetthefluxinformationlbLabel  matlab.ui.control.Label
        Step1SelecttheGSMmodelLabel   matlab.ui.control.Label
        TargetFluxEditField           matlab.ui.control.EditField
        TargetFluxEditFieldLabel      matlab.ui.control.Label
        ObjectivewoexpEditField       matlab.ui.control.EditField
        ObjectivewoexpEditFieldLabel  matlab.ui.control.Label
        updatefluxButton              matlab.ui.control.Button
        FluxvalueEditField            matlab.ui.control.NumericEditField
        FluxvalueEditFieldLabel       matlab.ui.control.Label
        FluxnameEditField             matlab.ui.control.EditField
        FluxnameEditFieldLabel        matlab.ui.control.Label
        calculateButton               matlab.ui.control.Button
        RxnknockoutEditField          matlab.ui.control.EditField
        RxnknockoutEditFieldLabel     matlab.ui.control.Label
        GeneknockoutEditField         matlab.ui.control.EditField
        GeneknockoutEditFieldLabel    matlab.ui.control.Label
        RxnsListBox                   matlab.ui.control.ListBox
        RxnsListBoxLabel              matlab.ui.control.Label
        SelectfileButton              matlab.ui.control.Button
        FluxBalanceAnalysisPanel      matlab.ui.container.Panel
        ExportDataButton              matlab.ui.control.Button
        rxnCheckBox                   matlab.ui.control.CheckBox
        MetsListBox                   matlab.ui.control.ListBox
        MetsListBoxLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        measuredtiterCheckBox         matlab.ui.control.CheckBox
        Lampfluxname_ub               matlab.ui.control.Lamp
        FluxvalueEditField_ub         matlab.ui.control.NumericEditField
        FluxvalueEditFieldLabel_2     matlab.ui.control.Label
        FluxnameEditField_ub          matlab.ui.control.EditField
        FluxnameEditFieldLabel_2      matlab.ui.control.Label
        updatefluxubButton            matlab.ui.control.Button
        Step3SetthefluxinformationubLabel  matlab.ui.control.Label
        GeneListBox                   matlab.ui.control.ListBox
        GeneListBoxLabel              matlab.ui.control.Label
        Step2SettheknockoutinfooptionalLabel  matlab.ui.control.Label
        knockoutButton                matlab.ui.control.Button
        MapnameEditField              matlab.ui.control.EditField
        MapnameEditFieldLabel         matlab.ui.control.Label
        MinFluxEditField              matlab.ui.control.NumericEditField
        MinFluxEditFieldLabel         matlab.ui.control.Label
        Lamptargetflux                matlab.ui.control.Lamp
        Lampobjective                 matlab.ui.control.Lamp
        Lampfluxname                  matlab.ui.control.Lamp
        MaxFluxEditField              matlab.ui.control.NumericEditField
        MaxFluxEditFieldLabel         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    modelLoaded = false;
    modelJoint  % This will hold the loaded model data
    ExcelData % measured data
    solution1  % Stores solution data
    solution2 % Stores solution2 data
    
    end
    
    methods (Access = private)
        
        function displayNodeName(event, G)
    % Get the index of the clicked node
    idx = event.Node;
    
    % Get the name of the node from the graph object
    name = G.Nodes.Name{idx};
    
    % Display the name as a tooltip
    tip = sprintf('Node: %s', name);
    set(gcf, 'ToolTip', tip);
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    % addpath('/Users/wenchingmei/Documents/MATLAB/cobratoolbox-master');
    initCobraToolbox(true);  % 'false' to avoid rechecking the prerequisites each time
    changeCobraSolver('glpk', 'LP');  % Replace 'glpk' with your preferred solver, e.g., 'gurobi', 'cplex'

        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)

            % Use uigetfile to open a file dialog for selecting a .mat file
    [file, path] = uigetfile('*.mat', 'Select a MATLAB file');
    if isequal(file, 0) || isequal(path, 0)
        uialert(app.UIFigure, 'No file selected', 'File Selection');
        return;  % Stop execution if no file is selected
    end

    % Construct the full file path
    fullPath = fullfile(path, file);

    % Load the .mat file
    data = load(fullPath);
    
    
    if isfield(data, 'modelJoint')
        app.modelJoint = data.modelJoint;
        app.modelLoaded = true;  % Set the flag to true
        uialert(app.UIFigure, 'Model loaded successfully.', 'Success', "Icon","success");
    else
        uialert(app.UIFigure, 'Model data not found in the file.', 'Error Loading Model',  "Icon","error");
    end
    

    % Check if the expected variable 'modelJoint' is in the loaded data
    if isfield(data, 'modelJoint') && isfield(data.modelJoint, 'rxns')
        % Handle the case where modelJoint is a structure and contains 'rxns'
        if isstruct(data.modelJoint)
        try
        % Access the rxns directly if it's expected to be an array of strings
        if iscell(data.modelJoint.rxns) && all(cellfun(@ischar, data.modelJoint.rxns))
            app.RxnsListBox.Items = data.modelJoint.rxns;             
        else
            uialert(app.UIFigure, 'The "rxns" field is not an array of strings.', 'Data Format Error');
        end
        
        if iscell(data.modelJoint.mets) && all(cellfun(@ischar, data.modelJoint.mets))
            app.MetsListBox.Items = data.modelJoint.mets;              
        else
            uialert(app.UIFigure, 'The "mets (measured)" field is not a cell array of strings.', 'Data Format Error'); 
        end
                    
                    
        if iscell(data.modelJoint.mets2) && all(cellfun(@ischar, data.modelJoint.mets2))
            app.MetsListBox.Items = data.modelJoint.mets2;              
        else
            % disp('The "mets2" field is not a cell array of strings.');
            uialert(app.UIFigure, 'The "mets (measured)" field is not a cell array of strings.', 'Data Format Error'); 
        end        
 
        if iscell(data.modelJoint.genes) && all(cellfun(@ischar, data.modelJoint.genes))
            app.GeneListBox.Items = data.modelJoint.genes;               
        else
            uialert(app.UIFigure, 'The "genes" field does not exist.', 'Data Format Error');
        end       


        % Debugging statements for mets2
%         if isfield(data.modelJoint, 'mets2')
%             disp('The "mets2" field exists.');
%             if iscell(data.modelJoint.mets2) && all(cellfun(@ischar, data.modelJoint.mets2))
%                 disp('The "mets2" field is a cell array of strings.');
%                 app.MetsListBox.Items = data.modelJoint.mets2;             
%             else
%                 disp('The "mets2" field is not a cell array of strings.');
%                 uialert(app.UIFigure, 'The "mets (measured)" field is not a cell array of strings.', 'Data Format Error');
%             end
%         else
%             disp('The "mets2" field does not exist.');
%             uialert(app.UIFigure, 'The "mets (measured)" field does not exist.', 'Data Format Error');
%         end
                
            catch ME
                uialert(app.UIFigure, ['Failed to process data: ', ME.message], 'Processing Error');
            end
        else
            uialert(app.UIFigure, 'The variable "modelJoint" is not a structure.', 'Data Type Error');
        end
    else
        uialert(app.UIFigure, 'The selected file does not contain the variable "modelJoint" or the "rxns/genes" field.', 'Data Error');
    end

            
        end

        % Button pushed function: knockoutButton
        function knockoutButtonPushed(app, event)


    % Retrieve the input from text fields
rxnIDs = strsplit(app.RxnknockoutEditField.Value, ',');
geneIDs = strsplit(app.GeneknockoutEditField.Value, ',');

% Check if there are reactions to knockout
if ~isempty(rxnIDs{1})
    for i = 1:length(rxnIDs)
        app.modelJoint = changeRxnBounds(app.modelJoint, strtrim(rxnIDs{i}), 0, 'b');
    end
end

% Check if there are gene to knockout
if ~isempty(geneIDs{1})
    for i = 1:length(geneIDs)
        app.modelJoint = changeRxnBounds(app.modelJoint, strtrim(geneIDs{i}), 0, 'b');
    end
end

        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
% Assuming 'value' is checked and triggers this process
value = app.measuredtiterCheckBox.Value;

if value
    % Extract data from the app model and Excel input
    LB = app.modelJoint.lb;
    UB = app.modelJoint.ub;
    b = app.modelJoint.b; 
    struc = full(app.modelJoint.S);
    time = table2array(app.ExcelData(1, 2:end));  % Assuming time is in the first row, from column 2 onward
    measured_mets = table2cell(app.ExcelData(2:end, 1));  % Extract metabolite names from first column, from row 2 onward
    b_values = table2array(app.ExcelData(2:end, 2:end));  % Extract b values from row 2 onward, column 2 onward

    computedSolution1 = [];
    computedSolution2 = [];
    for col = 1:size(b_values, 2)
        % Replace b vector in the model for each time step
        app.modelJoint.b = b_values(:, col);

        % Check if the target metabolite exists in the list
        if ~isempty(find(strcmp(app.modelJoint.mets2, app.TargetFluxEditField.Value), 1))
            % Run FBA based on the measured metabolites
            [v_observe, v_opt_fmincon] = userdefinedFBA(app.modelJoint, measured_mets);
            
            target_met = app.TargetFluxEditField.Value;
            target_met_index = find(strcmp(app.modelJoint.mets2, target_met));
            
            
            if ~isempty(target_met_index)
                % Continue with your calculation logic
                % e.g., appending results to solution or further processing
            else
                uialert(app.UIFigure, 'The target met is not found in the met list', 'Data Error');
                return; % Stop further execution if target metabolite is not found
            end
            
        app.Lampobjective.Color = 'g';
         % Run flux balance analysis
        rxnvalue = app.rxnCheckBox.Value;
        if rxnvalue
           computedSolution2 = [computedSolution2, v_opt_fmincon]; 
    
        else
           computedSolution1 = [computedSolution1, v_observe];
        end

        
        elseif ~isempty(find(strcmp(app.modelJoint.rxns, app.TargetFluxEditField.Value), 1))
            % Run FBA based on the measured metabolites
            [v_observe, v_opt_fmincon] = userdefinedFBA(app.modelJoint, measured_mets);
            
            target_rxn = app.TargetFluxEditField.Value;
            target_rxn_index = find(strcmp(app.modelJoint.rxns, target_rxn));
            if ~isempty(target_rxn_index)
                % Continue with your calculation logic
                % e.g., appending results to solution or further processing
            else
                uialert(app.UIFigure, 'The target rxn is not found in the rxn list', 'Data Error');
                return; % Stop further execution if target metabolite is not found
            end
        app.Lampobjective.Color = 'g';
         % Run flux balance analysis
        rxnvalue = app.rxnCheckBox.Value;
        if rxnvalue
           computedSolution2 = [computedSolution2, v_opt_fmincon]; 
    
        else
           computedSolution1 = [computedSolution1, v_observe];
        end


        else
            app.Lampobjective.Color = 'r';
            computedSolution1 = [];            
        end
        
        
    end
    rxnvalue = app.rxnCheckBox.Value;
    if rxnvalue
           target_rxn = app.TargetFluxEditField.Value;
           target_rxn_index = find(strcmp(app.modelJoint.rxns, target_rxn));
            if ~isempty(target_rxn_index)
                % Continue with your calculation logic
                % e.g., appending results to solution or further processing
            else
                uialert(app.UIFigure, 'The target rxn is not found in the met list', 'Data Error');
                return; % Stop further execution if target metabolite is not found
            end
            
            plot(app.UIAxes, time, computedSolution2(target_rxn_index,:), 'o-');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'rxn flux (conc/t)');
            title(app.UIAxes, ['Flux of ', target_rxn, ' over Time']);
            % Set x- and y-tick values
            xticks(app.UIAxes, time); % Assuming 'time' contains the x-tick positions
            ytick_values = linspace(  min(  computedSolution2(target_rxn_index,:)), max(computedSolution2(target_rxn_index,:))   , 10); % Create 10 y-ticks from min to max of solution
            yticks(app.UIAxes, ytick_values);

            % Optionally, set custom tick labels if needed
            xticklabels(app.UIAxes, cellstr(num2str(time')));
            yticklabels(app.UIAxes, cellstr(num2str(ytick_values')));  
    else
        
            target_met = app.TargetFluxEditField.Value;
            target_met_index = find(strcmp(app.modelJoint.mets2, target_met));
            if ~isempty(target_met_index)
                % Continue with your calculation logic
                % e.g., appending results to solution or further processing
            else
                uialert(app.UIFigure, 'The target met is not found in the met list', 'Data Error');
                return; % Stop further execution if target metabolite is not found
            end
            
            plot(app.UIAxes, time, computedSolution1(target_met_index,:), 'o-');
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'met flux (conc/t)');
            title(app.UIAxes, ['Flux of ', target_met, ' over Time']);
            % Set x- and y-tick values
            xticks(app.UIAxes, time); % Assuming 'time' contains the x-tick positions
            ytick_values = linspace(  min(  computedSolution1(target_met_index,:)), max(computedSolution1(target_met_index,:))   , 10); % Create 10 y-ticks from min to max of solution
            yticks(app.UIAxes, ytick_values);

            % Optionally, set custom tick labels if needed
            xticklabels(app.UIAxes, cellstr(num2str(time')));
            yticklabels(app.UIAxes, cellstr(num2str(ytick_values')));  
    end
            
            
 % Example: Storing the data in app properties
app.solution1 = computedSolution1;  % Replace `computedSolution` with your variable
app.solution2 = computedSolution2;  % Replace `computedSolution2` with your variable


   else

LB = app.modelJoint.lb;
UB = app.modelJoint.ub;
b = app.modelJoint.b;
struc = full(app.modelJoint.S);
if ~isempty(find(strcmp(app.modelJoint.rxns, app.ObjectivewoexpEditField.Value)));
    
    model = changeObjective(app.modelJoint, app.ObjectivewoexpEditField.Value, 1);
    app.Lampobjective.Color = 'g';
    % Run flux balance analysis
    solution = optimizeCbModel(model, 'max');
    solutionmin = optimizeCbModel(model, 'min');
else
    app.Lampobjective.Color = 'r';
    solution = [];
end


% solution = linprog(ff,[],[],struc,b,LB,UB); %optimizeCbModel(app.modelJoint,'max');
% check result
if ~isempty(solution.v) && isempty(app.MapnameEditField.Value)
    target = app.TargetFluxEditField.Value;
    targetindex = find(strcmp(app.modelJoint.rxns, target));
    
    if ~isempty(targetindex)
        app.MaxFluxEditField.Value = solution.v(targetindex);
        app.MinFluxEditField.Value = solutionmin.v(targetindex);
        
        app.MaxFluxEditField.BackgroundColor = [0.92,1.00,0.90];
        app.MinFluxEditField.BackgroundColor = [0.92,1.00,0.90];
        app.Lamptargetflux.Color = 'g';
    else
        app.Lamptargetflux.Color = 'r';
    end
    
    
        % Extract stoichiometric matrix and flux vector

    S = app.modelJoint.S;
    v = double(solution.v); % Make sure this covers all reactions

    numMetabolites = size(S, 1);
    numReactions = size(S, 2);

    % Check the dimensions
    if numReactions ~= length(v)
        error('Mismatch between number of reactions and length of flux vector.');
    end

    % Find non-zero entries in S (connections)
    [metIdx, rxnIdx, weights] = find(S);

    % Ensuring indices are within bounds
    if max(rxnIdx) > length(v)
        error('Reaction indices exceed the number of available flux values.');
    end


    metaboliteLabels = app.modelJoint.mets;
    reactionLabels = app.modelJoint.rxns;
    nodeLabels = [metaboliteLabels; reactionLabels];

    % Create edge list for the digraph
    startNodes = numMetabolites + rxnIdx; % Reaction nodes as start
    endNodes = metIdx; % Metabolite nodes as end

    % Ensure v has the same length as the number of reactions
    if length(v) ~= numReactions
        error('Length of flux vector does not match the number of reactions.');
    end

    % Create edge weights based on flux values
    edgeWeights = abs(v(rxnIdx)) ;

    % Create directed graph
    G = digraph(startNodes, endNodes, edgeWeights, nodeLabels);

    % Initialize variables to hold inflow and outflow for each node
    inflow = zeros(numnodes(G), 1);
    outflow = zeros(numnodes(G), 1);

    % Calculate inflow and outflow for each node
    for i = 1:numnodes(G)
        inflow(i) = sum(edgeWeights(inedges(G, i)));
        outflow(i) = sum(edgeWeights(outedges(G, i)));
    end

% Create a bar plot to represent inflow and outflow for each node
b = bar(app.UIAxes, [inflow, outflow], 'stacked');
lgd = legend(app.UIAxes, {'Inflow', 'Outflow'}, 'FontSize', 6);
lgd.Box = 'off'; % Remove the box outline
app.UIAxes.XTick = 1:numnodes(G);
app.UIAxes.XTickLabel = nodeLabels;
xlabel(app.UIAxes, 'Node');
ylabel(app.UIAxes, 'Flow');
title(app.UIAxes, 'Flow between Nodes');

% Rotate x-axis tick labels
app.UIAxes.XTickLabelRotation = 30;
% Set font size of x-axis tick labels
app.UIAxes.FontSize = 6; % Adjust font size of x-axis tick labels

% Enable panning and zooming interactions

% Setup Data Tips
% Here, we must ensure that each bar segment has its own data tip rows setup
% Setup Data Tips
for k = 1:numel(b)
    %row1 = dataTipTextRow('Type', repmat(b(k).DisplayName, size(b(k).YData)));
    row2 = dataTipTextRow('Value', b(k).YData);
    b(k).DataTipTemplate.DataTipRows = row2;
end

% Ensure interactions are enabled that allow for data tips to be shown
app.UIAxes.Interactions = [dataTipInteraction, panInteraction(), zoomInteraction()];


elseif ~isempty(solution.v) && ~isempty(app.MapnameEditField.Value)
    target = app.TargetFluxEditField.Value;
    targetindex = find(strcmp(app.modelJoint.rxns, target));
    if ~isempty(targetindex)
        app.MaxFluxEditField.Value = solution.v(targetindex);
        app.MinFluxEditField.Value = solutionmin.v(targetindex);
        
        app.MaxFluxEditField.BackgroundColor = [0.92,1.00,0.90];
        app.MinFluxEditField.BackgroundColor = [0.92,1.00,0.90];
        app.Lamptargetflux.Color = 'g';
    else
        app.Lamptargetflux.Color = 'r';
    end
    
    try
        
        filename = app.MapnameEditField.Value;
        % Construct the full file path
        % '/Users/wenchingmei/Documents/MATLAB/cobratoolbox-master/test/additionalTests/testMaps',
        mapfullPath = fullfile(filename);
        % Load the .mat file
        mapdata = load(mapfullPath);
        % Construct the full file path
        
        outputFormatOK = changeCbMapOutput('matlab');
        options.zeroFluxWidth = 0.1;
        options.rxnDirMultiplier = 10;
        drawFlux(mapdata.map, app.modelJoint, solution.v, options);
    catch
        % If an error occurs (e.g., map name not found), display an alert
        uialert(app.UIFigure, 'Map name not found!', 'Error', 'Icon', 'error');
    end

else 
    app.MaxFluxEditField.Value = -inf;
    app.MinFluxEditField.Value = -inf;
    app.MaxFluxEditField.BackgroundColor = [1.00,0.90,0.90];
    app.MinFluxEditField.BackgroundColor = [1.00,0.90,0.90];

end

   end

        end

        % Button pushed function: updatefluxButton
        function updatefluxButtonPushed(app, event)
% Assuming modelJoint.rxns is a cell array of reaction IDs
reactionID = app.FluxnameEditField.Value;  % The reaction ID you're looking for
index = find(strcmp(app.modelJoint.rxns, reactionID));
    if ~isempty(index)
        app.modelJoint.lb(index) = app.FluxvalueEditField.Value;
        app.Lampfluxname.Color = 'g';
    else
        app.Lampfluxname.Color = 'r';
    end
        end

        % Button pushed function: updatefluxubButton
        function updatefluxubButtonPushed(app, event)
% Assuming modelJoint.rxns is a cell array of reaction IDs
reactionID = app.FluxnameEditField_ub.Value;  % The reaction ID you're looking for
index = find(strcmp(app.modelJoint.rxns, reactionID));
    if ~isempty(index)
        app.modelJoint.ub(index) = app.FluxvalueEditField_ub.Value;
        app.Lampfluxname.Color = 'g';
    else
        app.Lampfluxname.Color = 'r';
    end
        end

        % Value changed function: measuredtiterCheckBox
        function measuredtiterCheckBoxValueChanged(app, event)
            value = app.measuredtiterCheckBox.Value;
            if value
                % Load data from Excel when checkbox is checked
                [file, path] = uigetfile('*.xlsx');
                if isequal(file, 0)
                    disp('User selected Cancel');
                else
                    % Read the Excel file
                    fullpath = fullfile(path, file);
                    data = readtable(fullpath);
                    app.ExcelData = readtable(fullpath); % Store data in the property

                    % Extract the first column (starting from the second row) as measured metabolites names
                    measured_mets = data{2:end, 1};     
                    % Extract the rest of the data as b values (starting from the second row and second column)
                    b_values = data{2:end, 2:end};

                    % Assuming modelJoint is passed as input or defined in the app
                    % Update modelJoint.b with the extracted b values
                    % Here, we assume that modelJoint.b is properly sized to match b_values
                    % modelJoint.b = zeros(size(b_values, 1), size(b_values, 2));
                    
                    % Update the table in the GUI to display the loaded data
                    app.UITable.Data = data;
                    app.UITable.ColumnName = data.Properties.VariableNames;
                    
                end
            else
                % Clear the table when checkbox is unchecked
                app.UITable.Data = {};
                app.UITable.ColumnName = {};
                app.ExcelData = table(); % Clear the stored data

            end

        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)

   % Check if either 'solution1' or 'solution2' exists
if ~isempty(app.solution1) || ~isempty(app.solution2)
    % Open a save dialog to choose a file location
    [file, path] = uiputfile('*.xlsx', 'Save Data As Excel File');
    
    % If the user selects a file
    if ischar(file)
        % Construct the full file path
        fullPath = fullfile(path, file);
        
        % Save solution1 with rxn/met names
        if ~isempty(app.solution1)
            % Combine rxn/met names with solution1
            rxnOrMetNames = app.modelJoint.mets2; % Replace with 'mets' if working with metabolites
            dataWithNames = table(rxnOrMetNames, app.solution1, 'VariableNames', {'Name', 'Time'});
            
            % Write to the Excel file in a new sheet
            writetable(dataWithNames, fullPath, 'Sheet', 'Solution1');
        end
        
        % Save solution2 with rxn/met names
        if ~isempty(app.solution2)
            % Combine rxn/met names with solution2
            rxnOrMetNames = app.modelJoint.rxns; % Replace with 'mets' if working with metabolites
            dataWithNames = table(rxnOrMetNames, app.solution2, 'VariableNames', {'Name', 'Time'});
            
            % Write to the Excel file in a new sheet
            writetable(dataWithNames, fullPath, 'Sheet', 'Solution2');
        end
        
        % Notify the user of success
        uialert(app.UIFigure, 'Data successfully saved to Excel!', 'Export Successful', 'Icon', 'success');
    else
        % If user cancels save dialog
        uialert(app.UIFigure, 'Export canceled.', 'Export Canceled', 'Icon', 'warning');
    end
else
    % Notify the user that no data is available to save
    uialert(app.UIFigure, 'No data available to export.', 'Export Error', 'Icon', 'error');
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FluxBalanceAnalysisPanel = uipanel(app.UIFigure);
            app.FluxBalanceAnalysisPanel.Title = 'Flux Balance Analysis';
            app.UIAxes = uiaxes(app.FluxBalanceAnalysisPanel);
            title(app.UIAxes, 'Metabolic Network Flux Visualization')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.MaxFluxEditFieldLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.MaxFluxEditFieldLabel.Text = 'Max Flux';
            app.MaxFluxEditField = uieditfield(app.FluxBalanceAnalysisPanel, 'numeric');
            app.Lampfluxname = uilamp(app.FluxBalanceAnalysisPanel);
            app.Lampobjective = uilamp(app.FluxBalanceAnalysisPanel);
            app.Lamptargetflux = uilamp(app.FluxBalanceAnalysisPanel);
            app.MinFluxEditFieldLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.MinFluxEditFieldLabel.Text = 'Min Flux';
            app.MinFluxEditField = uieditfield(app.FluxBalanceAnalysisPanel, 'numeric');
            app.MapnameEditFieldLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.MapnameEditFieldLabel.Text = 'Map name';
            app.MapnameEditField = uieditfield(app.FluxBalanceAnalysisPanel, 'text');
            app.MapnameEditField.Value = 'e.g., ecoli_core_map';
            app.knockoutButton = uibutton(app.FluxBalanceAnalysisPanel, 'push');
            app.knockoutButton.ButtonPushedFcn = createCallbackFcn(app, @knockoutButtonPushed, true);
            app.knockoutButton.Text = 'knockout';
            app.Step2SettheknockoutinfooptionalLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.Step2SettheknockoutinfooptionalLabel.Text = 'Step 2. Set the knockout info (optional)';
            app.GeneListBoxLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.GeneListBoxLabel.Text = 'Gene';
            app.GeneListBox = uilistbox(app.FluxBalanceAnalysisPanel);
            app.GeneListBox.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.Step3SetthefluxinformationubLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.Step3SetthefluxinformationubLabel.Text = 'Step 3. Set the flux information ub';
            app.updatefluxubButton = uibutton(app.FluxBalanceAnalysisPanel, 'push');
            app.updatefluxubButton.ButtonPushedFcn = createCallbackFcn(app, @updatefluxubButtonPushed, true);
            app.updatefluxubButton.Text = 'update flux ub';
            app.FluxnameEditFieldLabel_2 = uilabel(app.FluxBalanceAnalysisPanel);
            app.FluxnameEditFieldLabel_2.Text = 'Flux name';
            app.FluxnameEditField_ub = uieditfield(app.FluxBalanceAnalysisPanel, 'text');
            app.FluxnameEditField_ub.Value = 'optional';
            app.FluxvalueEditFieldLabel_2 = uilabel(app.FluxBalanceAnalysisPanel);
            app.FluxvalueEditFieldLabel_2.Text = 'Flux value';
            app.FluxvalueEditField_ub = uieditfield(app.FluxBalanceAnalysisPanel, 'numeric');
            app.Lampfluxname_ub = uilamp(app.FluxBalanceAnalysisPanel);
            app.measuredtiterCheckBox = uicheckbox(app.FluxBalanceAnalysisPanel);
            app.measuredtiterCheckBox.ValueChangedFcn = createCallbackFcn(app, @measuredtiterCheckBoxValueChanged, true);
            app.measuredtiterCheckBox.Text = 'measured titer';
            app.UITable = uitable(app.FluxBalanceAnalysisPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MetsListBoxLabel = uilabel(app.FluxBalanceAnalysisPanel);
            app.MetsListBoxLabel.Text = 'Mets';
            app.MetsListBox = uilistbox(app.FluxBalanceAnalysisPanel);
            app.MetsListBox.Items = {'met 1', 'met 2', 'met 3'};
            app.MetsListBox.Value = 'met 1';
            app.rxnCheckBox = uicheckbox(app.FluxBalanceAnalysisPanel);
            app.rxnCheckBox.Text = 'rxn';
            app.ExportDataButton = uibutton(app.FluxBalanceAnalysisPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'ExportDataButton';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.RxnsListBoxLabel = uilabel(app.UIFigure);
            app.RxnsListBoxLabel.Text = 'Rxns';
            app.RxnsListBox = uilistbox(app.UIFigure);
            app.RxnsListBox.Items = {'rxn1', 'rxn2', 'rxn3'};
            app.RxnsListBox.Value = 'rxn1';
            app.GeneknockoutEditFieldLabel = uilabel(app.UIFigure);
            app.GeneknockoutEditFieldLabel.Text = 'Gene knockout';
            app.GeneknockoutEditField = uieditfield(app.UIFigure, 'text');
            app.RxnknockoutEditFieldLabel = uilabel(app.UIFigure);
            app.RxnknockoutEditFieldLabel.Text = 'Rxn knockout';
            app.RxnknockoutEditField = uieditfield(app.UIFigure, 'text');
            app.RxnknockoutEditField.Value = 'e.g., SUCDi';
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.FluxnameEditFieldLabel = uilabel(app.UIFigure);
            app.FluxnameEditFieldLabel.Text = 'Flux name';
            app.FluxnameEditField = uieditfield(app.UIFigure, 'text');
            app.FluxnameEditField.Value = 'e.g., EX_glu_L(e)';
            app.FluxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.FluxvalueEditFieldLabel.Text = 'Flux value';
            app.FluxvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.updatefluxButton = uibutton(app.UIFigure, 'push');
            app.updatefluxButton.ButtonPushedFcn = createCallbackFcn(app, @updatefluxButtonPushed, true);
            app.updatefluxButton.Text = 'update flux';
            app.ObjectivewoexpEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectivewoexpEditFieldLabel.Text = 'Objective (wo exp)';
            app.ObjectivewoexpEditField = uieditfield(app.UIFigure, 'text');
            app.ObjectivewoexpEditField.Value = 'e.g., Biomass_Ecoli_core_w_GAM';
            app.TargetFluxEditFieldLabel = uilabel(app.UIFigure);
            app.TargetFluxEditFieldLabel.Text = 'Target Flux';
            app.TargetFluxEditField = uieditfield(app.UIFigure, 'text');
            app.TargetFluxEditField.Value = 'e.g., EX_co2(e)';
            app.Step1SelecttheGSMmodelLabel = uilabel(app.UIFigure);
            app.Step1SelecttheGSMmodelLabel.Text = 'Step 1. Select the GSM model';
            app.Step3SetthefluxinformationlbLabel = uilabel(app.UIFigure);
            app.Step3SetthefluxinformationlbLabel.Text = 'Step 3. Set the flux information lb';
            app.Step4CalculatethefluxesLabel = uilabel(app.UIFigure);
            app.Step4CalculatethefluxesLabel.Text = 'Step 4. Calculate the fluxes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_Dec_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end